classdef textfileanalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        FileAnalysisTab                matlab.ui.container.Tab
        DistributionFittingLabel       matlab.ui.control.Label
        FitDistButton                  matlab.ui.control.Button
        BrowseCheck                    matlab.ui.control.Label
        meanvalue_2                    matlab.ui.control.Label
        skewnessvalue_2                matlab.ui.control.Label
        kurtosisvalue_2                matlab.ui.control.Label
        variancevalue_2                matlab.ui.control.Label
        CDFofRandomVariableLabel       matlab.ui.control.Label
        PMFofRandomVariableLabel       matlab.ui.control.Label
        Occurencesof26CharactersLabel  matlab.ui.control.Label
        PlotButtonCDF                  matlab.ui.control.Button
        PlotButtonPMF                  matlab.ui.control.Button
        PlotButtonprob                 matlab.ui.control.Button
        kurtosisvalue                  matlab.ui.control.Label
        skewnessvalue                  matlab.ui.control.Label
        variancevalue                  matlab.ui.control.Label
        meanvalue                      matlab.ui.control.Label
        KurtosisofRVXLabel             matlab.ui.control.Label
        SkewnessofRVXLabel             matlab.ui.control.Label
        VarianceofRVXLabel             matlab.ui.control.Label
        MeanofRVXLabel                 matlab.ui.control.Label
        StatisticalPropertiesLabel     matlab.ui.control.Label
        mostuse3                       matlab.ui.control.Label
        mostuse5                       matlab.ui.control.Label
        mostuse1                       matlab.ui.control.Label
        mostuse2                       matlab.ui.control.Label
        mostuse4                       matlab.ui.control.Label
        MostUsedLettersLabel           matlab.ui.control.Label
        AnalysisLabel                  matlab.ui.control.Label
        AnalyzeButton                  matlab.ui.control.Button
        BrowseButton                   matlab.ui.control.Button
        ImportingTextFileLabel         matlab.ui.control.Label
        PlotsTab                       matlab.ui.container.Tab
        DistributionanalysisTab        matlab.ui.container.Tab
        Label_fitmetest                matlab.ui.control.Label
        UsingthefitmethisLabel         matlab.ui.control.Label
        DistributionTest2Label         matlab.ui.control.Label
        Label_allfittest               matlab.ui.control.Label
        UsingtheallfitdistLabel        matlab.ui.control.Label
        DistributionTest1Label         matlab.ui.control.Label
        TextFileAnalyzerLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        txtfilename % txtfile variable.
        filepath % txtfile path variable.
        fullpath%
        FID; % File ID
        speak; % the property for TTS.
        X=zeros(1,26); % the random variable for occurences of the characters.
        meanX=0;ax;ax2;ax3;ax4
        varX=0;stdX=0;zX=0;D;PD;F;
        skewnessX=0;
        kurtosisX=0;probX;
        max5_ind=[];max1;max2;max3;max4;max5;max5arr=[];totalchars;
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             
             NET.addAssembly('System.Speech');
             app.speak= System.Speech.Synthesis.SpeechSynthesizer;
             app.speak.Volume=35;
             try
                app.speak.SelectVoice("Microsoft Zira Desktop");
             catch
                 app.speak.SelectVoice("Microsoft Hazel Desktop");
             end
             Speak(app.speak,'Welcome To  text statistical analyzer!');
              app.PlotsTab.AutoResizeChildren= 'off';
              app.AnalyzeButton.Enable= false;
              app.PlotButtonprob.Enable= false;
              app.PlotButtonCDF.Enable= false;
              app.PlotButtonPMF.Enable= false;
              app.FitDistButton.Enable=false;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
  
         [app.txtfilename,app.filepath]  = uigetfile('*.txt');
         figure(app.UIFigure);
         app.fullpath=fullfile(app.filepath,app.txtfilename);
         if isequal(app.txtfilename,0)
            app.BrowseCheck.Text=('User selected Cancel, please select a text file.');
         else
              path_label = sprintf('User selected %s ' ,app.fullpath);
              app.BrowseCheck.Text=(path_label);
         end
           app.AnalyzeButton.Enable=true;
           app.FitDistButton.Enable=true;
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            chars_readinit= fileread(app.fullpath);
            chars_readinit2= convertCharsToStrings(chars_readinit);
            chars_read=lower(chars_readinit2);
            for i = 1 : 26
              app.X(i)= count(chars_read,char(96+i));
            end
            app.totalchars=sum(app.X(1,:));
           
            maxchars= maxk(app.X,5);
            for i= 1:length(app.X)
                for j = 1:length(maxchars)
                   if (app.X(i) == maxchars(j))
                           app.max5_ind= [app.max5_ind i];
                   end
                end
            end    
            app.max1=app.X(app.max5_ind(1));app.max2=app.X(app.max5_ind(2)) ;
            app.max3=app.X(app.max5_ind(3));app.max4=app.X(app.max5_ind(4)) ;app.max5=app.X(app.max5_ind(5)) ;
            app.max5arr=[app.max1 , app.max2 , app.max3 , app.max4 , app.max5  ];
 
            max1label = sprintf("%s: occured (%d) times.", char(96 + app.max5_ind(1)),app.X(app.max5_ind(1)));
            max2label = sprintf("%s: occured (%d) times.", char(96 + app.max5_ind(2)),app.X(app.max5_ind(2)));
            max3label = sprintf("%s: occured (%d) times.", char(96 + app.max5_ind(3)),app.X(app.max5_ind(3)));
            max4label = sprintf("%s: occured (%d) times.", char(96 +app.max5_ind(4)),app.X(app.max5_ind(4)));
            max5label = sprintf("%s: occured (%d) times.", char(96 +app.max5_ind(5)),app.X(app.max5_ind(5)));
            app.mostuse1.Text=max1label; app.mostuse2.Text=max2label;  app.mostuse3.Text=max3label; app.mostuse4.Text=max4label; app.mostuse5.Text=max5label;
            app.PlotButtonprob.Enable= true;
            app.PlotButtonCDF.Enable=  true;
            app.PlotButtonPMF.Enable=  true; EX_2=0;
            for i=1:26
                 app.meanX= app.meanX + (i * (app.X(i)/app.totalchars)  );   
            end          
            for i=1:26
               EX_2= EX_2 + ((i^2) * (app.X(i)/app.totalchars)); % Expectation of X Squared (X^2)
            end    
            app.varX= EX_2 - (app.meanX)^2; app.stdX=sqrt(app.varX);
            for i =1:26
                app.zX(i)=((i-app.meanX)/app.stdX);
                app.skewnessX= app.skewnessX + ((app.zX(i) ^ 3) * (app.X(i)/app.totalchars));
            end
            for i =1:26
                app.kurtosisX= app.kurtosisX + ((app.zX(i) ^ 4) * (app.X(i)/app.totalchars));
            end
            meanlabel= string(app.meanX);
            varlabel= string(app.varX);
            skewlabel= string(app.skewnessX);
            kurtlabel= string(app.kurtosisX);
            app.meanvalue.Text= meanlabel ;
            app.variancevalue.Text= varlabel ;
            app.skewnessvalue.Text= skewlabel ;
            app.kurtosisvalue.Text= kurtlabel ;
            Speak(app.speak,'Plotting results are in the Plots tab');


        end

        % Button pushed function: PlotButtonprob
        function PlotButtonprobPushed(app, event)
       
             y_ax=zeros(1,26);
             x_ax=(1:1:26);
            for i = 1:26
              y_ax(i)= app.X(i);
            end
            
             x_ax_char= {'a','b','c','d','e','f','g','h','i','j','k','l','m','n','o','p','q','r','s','t','u','v','w','x','y','z'};
            
             app.ax = subplot(4,1,1,'Parent',app.PlotsTab);
             stem(app.ax,x_ax,y_ax);
             xticks(app.ax,(1:26));
             xticklabels(app.ax,x_ax_char) 
             title(app.ax,' N. of occurences  vs Letters ', "FontSize", 14);
             xlabel(app.ax,'The letter', "FontSize", 12);ylabel(app.ax,'No. of occurences', "FontSize", 10);


        end

        % Button pushed function: PlotButtonPMF
        function PlotButtonPMFPushed(app, event)
             x_ax=(1:1:26);
             y_ax2=zeros(1,26);
          %   x_ax_char= {'a','b','c','d','e','f','g','h','i','j','k','l','m','n','o','p','q','r','s','t','u','v','w','x','y','z'};

            for i = 1:26
              y_ax2(i)= (app.X(i) / app.totalchars);
            end
             app.probX= y_ax2;
            app.ax2 = subplot(3,1,2,'Parent',app.PlotsTab);
             stem(app.ax2,x_ax,y_ax2);
             xticks(app.ax2,(1:26));
             %xticklabels(app.ax2,x_ax_char)
             title(app.ax2,' PMF of X vs X ', "FontSize", 14);xlabel(app.ax,'The letter', "FontSize", 12);
             ylabel(app.ax2,'Probability of occurences', "FontSize", 10);


        end

        % Button pushed function: PlotButtonCDF
        function PlotButtonCDFPushed(app, event)
            x_ax=(1:1:26);
            y_ax3= cumsum(app.probX);
            app.ax3 = subplot(3,1,3,'Parent',app.PlotsTab);
            stem(app.ax3,x_ax,y_ax3);
            xticks(app.ax3,(1:26));      
            title(app.ax3,' CDF of X vs X ', "FontSize", 14);xlabel(app.ax,'The letter', "FontSize", 12);
            ylabel(app.ax3,'Cumulative Probability of occurences', "FontSize", 10);
    

        end

        % Button pushed function: FitDistButton
        function FitDistButtonPushed(app, event)
            Speak(app.speak,'Results are in Distribution Analysis Tab and plots are in new separate figures windows');
            [app.D, app.PD] = allfitdist(app.X, 'NLogL','DISCRETE','PDF');
            msg=('dist name:  { %s } \n dist pramaters: \t { %s , %s } \n paramters values: \n \t { %s , %s } \n NLogL Value: {%s} \n');
            s=sprintf(msg,string(app.D(1).DistName),string(app.D(1).ParamNames(:,:)),string(app.D(1).Params()),string(app.D(1).NLogL));
            app.Label_allfittest.Text=s;
 
            app.F=fitmethis(app.X);
            msg=('dist name:  { %s }  \n dist pramaters: {  %s , %s }  \n NLogL Value: { %s } \n');
            z=sprintf(msg,string(app.F(1).name),string(app.F(1).par),string(app.F(1).LL));
            app.Label_fitmetest.Text=z;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextFileAnalyzerLabel = uilabel(app.UIFigure);
            app.TextFileAnalyzerLabel.Text = 'Text File Analyzer';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileAnalysisTab = uitab(app.TabGroup);
            app.FileAnalysisTab.Title = 'File Analysis';
            app.ImportingTextFileLabel = uilabel(app.FileAnalysisTab);
            app.ImportingTextFileLabel.Text = 'Importing Text File:';
            app.BrowseButton = uibutton(app.FileAnalysisTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = {'Browse'; ''};
            app.AnalyzeButton = uibutton(app.FileAnalysisTab, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.AnalysisLabel = uilabel(app.FileAnalysisTab);
            app.AnalysisLabel.Text = 'Analysis:';
            app.MostUsedLettersLabel = uilabel(app.FileAnalysisTab);
            app.MostUsedLettersLabel.Text = {'Most Used  Letters:'; ''};
            app.mostuse4 = uilabel(app.FileAnalysisTab);
            app.mostuse4.Text = '';
            app.mostuse2 = uilabel(app.FileAnalysisTab);
            app.mostuse2.Text = '';
            app.mostuse1 = uilabel(app.FileAnalysisTab);
            app.mostuse1.Text = '';
            app.mostuse5 = uilabel(app.FileAnalysisTab);
            app.mostuse5.Text = '';
            app.mostuse3 = uilabel(app.FileAnalysisTab);
            app.mostuse3.Text = '';
            app.StatisticalPropertiesLabel = uilabel(app.FileAnalysisTab);
            app.StatisticalPropertiesLabel.Text = 'Statistical Properties:';
            app.MeanofRVXLabel = uilabel(app.FileAnalysisTab);
            app.MeanofRVXLabel.Text = 'Mean of RV (X):';
            app.VarianceofRVXLabel = uilabel(app.FileAnalysisTab);
            app.VarianceofRVXLabel.Text = 'Variance of RV (X):';
            app.SkewnessofRVXLabel = uilabel(app.FileAnalysisTab);
            app.SkewnessofRVXLabel.Text = 'Skewness of RV (X):';
            app.KurtosisofRVXLabel = uilabel(app.FileAnalysisTab);
            app.KurtosisofRVXLabel.Text = 'Kurtosis of RV (X):';
            app.meanvalue = uilabel(app.FileAnalysisTab);
            app.meanvalue.Text = '';
            app.variancevalue = uilabel(app.FileAnalysisTab);
            app.variancevalue.Text = '';
            app.skewnessvalue = uilabel(app.FileAnalysisTab);
            app.skewnessvalue.Text = '';
            app.kurtosisvalue = uilabel(app.FileAnalysisTab);
            app.kurtosisvalue.Text = '';
            app.PlotButtonprob = uibutton(app.FileAnalysisTab, 'push');
            app.PlotButtonprob.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonprobPushed, true);
            app.PlotButtonprob.Text = 'Plot';
            app.PlotButtonPMF = uibutton(app.FileAnalysisTab, 'push');
            app.PlotButtonPMF.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPMFPushed, true);
            app.PlotButtonPMF.Text = 'Plot';
            app.PlotButtonCDF = uibutton(app.FileAnalysisTab, 'push');
            app.PlotButtonCDF.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonCDFPushed, true);
            app.PlotButtonCDF.Text = 'Plot';
            app.Occurencesof26CharactersLabel = uilabel(app.FileAnalysisTab);
            app.Occurencesof26CharactersLabel.Text = 'Occurences of 26 Characters:';
            app.PMFofRandomVariableLabel = uilabel(app.FileAnalysisTab);
            app.PMFofRandomVariableLabel.Text = {'PMF of Random Variable:'; ''};
            app.CDFofRandomVariableLabel = uilabel(app.FileAnalysisTab);
            app.CDFofRandomVariableLabel.Text = {'CDF of Random Variable:'; ''};
            app.variancevalue_2 = uilabel(app.FileAnalysisTab);
            app.variancevalue_2.Text = '';
            app.kurtosisvalue_2 = uilabel(app.FileAnalysisTab);
            app.kurtosisvalue_2.Text = '';
            app.skewnessvalue_2 = uilabel(app.FileAnalysisTab);
            app.skewnessvalue_2.Text = '';
            app.meanvalue_2 = uilabel(app.FileAnalysisTab);
            app.meanvalue_2.Text = '';
            app.BrowseCheck = uilabel(app.FileAnalysisTab);
            app.BrowseCheck.Text = '';
            app.FitDistButton = uibutton(app.FileAnalysisTab, 'push');
            app.FitDistButton.ButtonPushedFcn = createCallbackFcn(app, @FitDistButtonPushed, true);
            app.FitDistButton.Text = 'Fit Dist.';
            app.DistributionFittingLabel = uilabel(app.FileAnalysisTab);
            app.DistributionFittingLabel.Text = 'Distribution Fitting:';
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.DistributionanalysisTab = uitab(app.TabGroup);
            app.DistributionanalysisTab.Title = 'Distribution analysis';
            app.DistributionTest1Label = uilabel(app.DistributionanalysisTab);
            app.DistributionTest1Label.Text = {'Distribution Test 1:'; ''};
            app.UsingtheallfitdistLabel = uilabel(app.DistributionanalysisTab);
            app.UsingtheallfitdistLabel.Text = {'Using the allfitdist():'; ''};
            app.Label_allfittest = uilabel(app.DistributionanalysisTab);
            app.Label_allfittest.Text = '';
            app.DistributionTest2Label = uilabel(app.DistributionanalysisTab);
            app.DistributionTest2Label.Text = {'Distribution Test 2:'; ''};
            app.UsingthefitmethisLabel = uilabel(app.DistributionanalysisTab);
            app.UsingthefitmethisLabel.Text = {'Using the fitmethis():'; ''};
            app.Label_fitmetest = uilabel(app.DistributionanalysisTab);
            app.Label_fitmetest.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = textfileanalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end