classdef SPCalc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MainOperationsTab             matlab.ui.container.Tab
        ImportantNoteInputsmustbeXandtLabel  matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        InputNotLoadedYetLabel        matlab.ui.control.Label
        getButton_3                   matlab.ui.control.Button
        setButton_3                   matlab.ui.control.Button
        getButton_2                   matlab.ui.control.Button
        Label_3                       matlab.ui.control.Label
        CalculatingTimeACFofnthLabel  matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        TheSampleNoEditField_2        matlab.ui.control.NumericEditField
        TheSampleNoEditField_2Label   matlab.ui.control.Label
        TimeMeanLabel                 matlab.ui.control.Label
        PlotButton_4                  matlab.ui.control.Button
        PowerSpectralDensityLabel     matlab.ui.control.Label
        PlotButton_3                  matlab.ui.control.Button
        StatACFEditField_2            matlab.ui.control.NumericEditField
        StatACFEditField_2Label       matlab.ui.control.Label
        StatACFEditField              matlab.ui.control.NumericEditField
        PlotButton_2                  matlab.ui.control.Button
        EnsemblemeanLabel             matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        SampleFunctionsLabel          matlab.ui.control.Label
        TimeACFLabel                  matlab.ui.control.Label
        PlottingLabel                 matlab.ui.control.Label
        CalculatingtimemeanofspecificsampleFunctionLabel_2  matlab.ui.control.Label
        getButton                     matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        TotalAvgPowerLabel            matlab.ui.control.Label
        CalculatingTotalaveragepoweroftheprocessLabel  matlab.ui.control.Label
        setButton_2                   matlab.ui.control.Button
        setButton                     matlab.ui.control.Button
        TheSampleNoEditField          matlab.ui.control.NumericEditField
        TheSampleNoEditFieldLabel     matlab.ui.control.Label
        LoadButton                    matlab.ui.control.Button
        NoofsampleFunctionsEditField  matlab.ui.control.NumericEditField
        NoofsampleFunctionsEditFieldLabel  matlab.ui.control.Label
        LoadingInputsLabel            matlab.ui.control.Label
        SamplesPlotTab                matlab.ui.container.Tab
        RPPropertiesPlotTab           matlab.ui.container.Tab
        StochasticProcessesCalculatorLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        inputs ;%inputs variables
        M;%no.of sample functions
        ax;N;N2;pxx;
       speak;ACFM;
        
    end
    
    methods (Access = public)
        
        function ACFCALC(app)
             x= app.inputs.X;
              [~,c]=size(app.inputs.X);
              app.ACFM=zeros(c);
            for m = 1 :c
                for n = 1 : c
                   app.ACFM(m,n)=mean(x(:,m).*x(:,n));
                end
            end
          
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pause(1);
             NET.addAssembly('System.Speech');
             app.speak= System.Speech.Synthesis.SpeechSynthesizer;
             app.speak.Volume=45;
             try
                app.speak.SelectVoice("Microsoft Zira Desktop");
             catch
                 app.speak.SelectVoice("Microsoft Hazel Desktop");
             end
             Speak(app.speak,'Welcome To the coolest Stochastic Processes Calculator!');
             app.setButton.Enable= false;
             app.setButton_2.Enable= false;
             app.setButton_3.Enable= false;
             app.getButton.Enable= false;
             app.getButton_2.Enable= false;
             app.getButton_3.Enable= false;
             app.PlotButton.Enable= false;
             app.PlotButton_2.Enable= false;
             app.PlotButton_3.Enable= false;
             app.PlotButton_4.Enable= false;

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
        [filename, foldername] = uigetfile('*.mat', 'Select a mat file');
        
        fullname = fullfile(foldername, filename);
        figure(app.UIFigure);
        if isequal(filename,0)
            app.InputNotLoadedYetLabel.Text=("User selected Cancel");
        else
          temp = load(fullname);
           app.inputs= temp;
           app.InputNotLoadedYetLabel.Text=("Loaded The Input From inputs.mat : X(t) Matrix and Time t vector.");
             app.setButton.Enable= true;
             app.setButton_2.Enable= true;
             app.setButton_3.Enable= true;
             app.getButton.Enable= true;
             app.getButton_2.Enable= true;
             app.getButton_3.Enable= true;
             app.PlotButton.Enable= true;
             app.PlotButton_2.Enable= true;
             app.PlotButton_3.Enable= true;
             app.PlotButton_4.Enable= true;
        end
           ACFCALC(app);
        end

        % Button pushed function: setButton
        function setButtonPushed(app, event)
            app.M =app.NoofsampleFunctionsEditField.Value;
            if app.M==0
                app.Label_5.Text=('May you please change the value other than zero');
            else
            labeltext=sprintf('%i',app.M);
            end
            app.Label_5.Text=(labeltext);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
           app.SamplesPlotTab.AutoResizeChildren= 'off';
         if app.M~=0
           Speak(app.speak,'Plotting the Sample Functions in the Samples Plot Tab and in a seperate figure window.');
         
           for i = 1 : app.M  
               app.ax = subplot(app.M,1,i,'Parent',app.SamplesPlotTab);
                plot(app.ax,app.inputs.t,app.inputs.X(i,:));
                title(app.ax,' X(t) vs. time');xlabel(app.ax,'t');ylabel(app.ax,'X(t)');
           end
              figure('Name','Sample Func.s')
          for i = 1 : app.M  
            app.ax = subplot(app.M,1,i);
            plot(app.ax,app.inputs.t,app.inputs.X(i,:));
            title(app.ax,' X(t) vs. time');xlabel(app.ax,'t');ylabel(app.ax,'X(t)');
          end
         end    
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            app.RPPropertiesPlotTab.AutoResizeChildren= 'off';
            x=mean(app.inputs.X); 
            Speak(app.speak,'Plotting the Ensemble Mean inside the RP Properties Plot Tab and in a seperate figure window.');
            app.ax = subplot(3,1,1,'Parent',app.RPPropertiesPlotTab);
            plot(app.ax,app.inputs.t,x);
            title(app.ax,' Ensemble Mean vs. time');xlabel(app.ax,'t');ylabel(app.ax,'Mean(X(t))');
            figure('Name','Ensemble Mean')
            app.ax = subplot(1,1,1);
            plot(app.ax,app.inputs.t,x);
            title(app.ax,' Ensemble Mean vs. time');xlabel(app.ax,'t');ylabel(app.ax,'Mean(X(t))');
             
        end

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)
            i=app.StatACFEditField.Value;j=app.StatACFEditField_2.Value;
            %a=app.inputs.X(:,m);b=app.inputs.X(:,n);
           
            app.RPPropertiesPlotTab.AutoResizeChildren= 'off';
            %X=transpose(x);
            Speak(app.speak,'Plotting the Statistical ACF inside the RP Properties Plot Tab and in a seperate figure window.');
            [~,c]=size(app.inputs.X);
            app.Label_6.Text=sprintf('%.5f',app.ACFM(i,j));
             
            app.ax = subplot(3,1,2,'Parent',app.RPPropertiesPlotTab);
            s=surf(app.ax,1:1:c,1:1:c,app.ACFM);
            s.EdgeColor='none';
            title(app.ax,' Statistical ACF');xlabel(app.ax,'X(i)');ylabel(app.ax,'X(j)');zlabel(app.ax,'ACF');
            figure('Name','Statistical ACF')
            subplot(1,1,1);
            s=surf(1:1:c,1:1:c,app.ACFM);s.EdgeColor='none';
            title(' Statistical ACF');xlabel('X(i)');ylabel('X(j)');zlabel('ACF');
            
        end

        % Button pushed function: setButton_2
        function setButton_2Pushed(app, event)
           app.N= app.TheSampleNoEditField.Value;
           
           
        end

        % Button pushed function: getButton_2
        function getButton_2Pushed(app, event)
         if app.N~=0
           
          X=(sum(app.inputs.X(app.N,:),'double'))*(app.inputs.t(2)-app.inputs.t(1));
         app.Label_2.Text=sprintf('%.6f',X);
         end
        end

        % Button pushed function: PlotButton_4
        function PlotButton_4Pushed(app, event)
            Y=(abs(fft(app.inputs.X , size(app.inputs.X,2) ,2)).^2);
            X=Y/length(app.inputs.t);
            Z=fftshift(mean(X));  
             Speak(app.speak,'Plotting the Power Spectral Density inside the RP Properties Plot Tab and in a seperate figure window.');
            app.RPPropertiesPlotTab.AutoResizeChildren= 'off';
            app.ax = subplot(3,1,3,'Parent',app.RPPropertiesPlotTab);
            plot(app.ax,Z);
            title(app.ax,' PSD');xlabel(app.ax,'Frequency(HZ)');ylabel(app.ax,'Power Amplitude');
            figure('Name','PSD')
            subplot(1,1,1);
            plot(Z); 
            title(app.ax,' PSD');xlabel(app.ax,'Frequency(HZ)');ylabel(app.ax,'Power Amplitude');
             
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Speak(app.speak,'I hope You liked my interactive speech,Goodbye!');
            delete(app);
           
        end

        % Button pushed function: setButton_3
        function setButton_3Pushed(app, event)
            app.N2=app.TheSampleNoEditField_2.Value;
        end

        % Button pushed function: getButton_3
        function getButton_3Pushed(app, event)
  X=app.inputs.X;n=app.N2;
 if n~=0
  [~,c]=size(X); taw=0;
   for i=1:c
       for j= 1:c
           
            if(j+taw)>c
                 break
            end
           x(i,j)=( X(n,j)*X(n,j+taw));
       end
        taw=taw+1;
   
  y=sum(x,2);z=mean(y)*((app.inputs.t(2)-app.inputs.t(1)));
  app.Label_3.Text=sprintf('%.5f',z);
  end
 end
        end

        % Button pushed function: getButton
        function getButtonPushed(app, event)
         x=app.ACFM(1,1);
            app.Label.Text=sprintf('%.4f',x);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StochasticProcessesCalculatorLabel = uilabel(app.UIFigure);
            app.StochasticProcessesCalculatorLabel.Text = 'Stochastic Processes Calculator';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainOperationsTab = uitab(app.TabGroup);
            app.MainOperationsTab.Title = 'Main Operations';
            app.LoadingInputsLabel = uilabel(app.MainOperationsTab);
            app.LoadingInputsLabel.Text = 'Loading Inputs:';
            app.NoofsampleFunctionsEditFieldLabel = uilabel(app.MainOperationsTab);
            app.NoofsampleFunctionsEditFieldLabel.Text = 'No. of sample Functions:';
            app.NoofsampleFunctionsEditField = uieditfield(app.MainOperationsTab, 'numeric');
            app.LoadButton = uibutton(app.MainOperationsTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.TheSampleNoEditFieldLabel = uilabel(app.MainOperationsTab);
            app.TheSampleNoEditFieldLabel.Text = 'The Sample No.:';
            app.TheSampleNoEditField = uieditfield(app.MainOperationsTab, 'numeric');
            app.setButton = uibutton(app.MainOperationsTab, 'push');
            app.setButton.ButtonPushedFcn = createCallbackFcn(app, @setButtonPushed, true);
            app.setButton.Text = 'set';
            app.setButton_2 = uibutton(app.MainOperationsTab, 'push');
            app.setButton_2.ButtonPushedFcn = createCallbackFcn(app, @setButton_2Pushed, true);
            app.setButton_2.Text = 'set';
            app.CalculatingTotalaveragepoweroftheprocessLabel = uilabel(app.MainOperationsTab);
            app.CalculatingTotalaveragepoweroftheprocessLabel.Text = 'Calculating Total average power of the process:';
            app.TotalAvgPowerLabel = uilabel(app.MainOperationsTab);
            app.TotalAvgPowerLabel.Text = 'Total Avg. Power:';
            app.Label = uilabel(app.MainOperationsTab);
            app.Label.Text = '';
            app.getButton = uibutton(app.MainOperationsTab, 'push');
            app.getButton.ButtonPushedFcn = createCallbackFcn(app, @getButtonPushed, true);
            app.getButton.Text = 'get';
            app.CalculatingtimemeanofspecificsampleFunctionLabel_2 = uilabel(app.MainOperationsTab);
            app.CalculatingtimemeanofspecificsampleFunctionLabel_2.Text = 'Calculating time mean of specific sample Function:';
            app.PlottingLabel = uilabel(app.MainOperationsTab);
            app.PlottingLabel.Text = 'Plotting:';
            app.TimeACFLabel = uilabel(app.MainOperationsTab);
            app.TimeACFLabel.Text = 'Time ACF:';
            app.SampleFunctionsLabel = uilabel(app.MainOperationsTab);
            app.SampleFunctionsLabel.Text = 'Sample Functions:';
            app.PlotButton = uibutton(app.MainOperationsTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.EnsemblemeanLabel = uilabel(app.MainOperationsTab);
            app.EnsemblemeanLabel.Text = 'Ensemble mean:';
            app.PlotButton_2 = uibutton(app.MainOperationsTab, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.StatACFEditField = uieditfield(app.MainOperationsTab, 'numeric');
            app.StatACFEditField_2Label = uilabel(app.MainOperationsTab);
            app.StatACFEditField_2Label.Text = 'Stat. ACF:';
            app.StatACFEditField_2 = uieditfield(app.MainOperationsTab, 'numeric');
            app.PlotButton_3 = uibutton(app.MainOperationsTab, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Text = 'Plot';
            app.PowerSpectralDensityLabel = uilabel(app.MainOperationsTab);
            app.PowerSpectralDensityLabel.Text = 'Power Spectral Density:';
            app.PlotButton_4 = uibutton(app.MainOperationsTab, 'push');
            app.PlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_4Pushed, true);
            app.PlotButton_4.Text = 'Plot';
            app.TimeMeanLabel = uilabel(app.MainOperationsTab);
            app.TimeMeanLabel.Text = 'Time-Mean:';
            app.TheSampleNoEditField_2Label = uilabel(app.MainOperationsTab);
            app.TheSampleNoEditField_2Label.Text = 'The Sample No.:';
            app.TheSampleNoEditField_2 = uieditfield(app.MainOperationsTab, 'numeric');
            app.Label_2 = uilabel(app.MainOperationsTab);
            app.Label_2.Text = '';
            app.CalculatingTimeACFofnthLabel = uilabel(app.MainOperationsTab);
            app.CalculatingTimeACFofnthLabel.Text = 'Calculating Time ACF of n-th:';
            app.Label_3 = uilabel(app.MainOperationsTab);
            app.Label_3.Text = '';
            app.getButton_2 = uibutton(app.MainOperationsTab, 'push');
            app.getButton_2.ButtonPushedFcn = createCallbackFcn(app, @getButton_2Pushed, true);
            app.getButton_2.Text = 'get';
            app.setButton_3 = uibutton(app.MainOperationsTab, 'push');
            app.setButton_3.ButtonPushedFcn = createCallbackFcn(app, @setButton_3Pushed, true);
            app.setButton_3.Text = 'set';
            app.getButton_3 = uibutton(app.MainOperationsTab, 'push');
            app.getButton_3.ButtonPushedFcn = createCallbackFcn(app, @getButton_3Pushed, true);
            app.getButton_3.Text = 'get';
            app.InputNotLoadedYetLabel = uilabel(app.MainOperationsTab);
            app.InputNotLoadedYetLabel.Text = 'Input Not Loaded Yet';
            app.Label_5 = uilabel(app.MainOperationsTab);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.MainOperationsTab);
            app.Label_6.Text = '';
            app.ImportantNoteInputsmustbeXandtLabel = uilabel(app.MainOperationsTab);
            app.ImportantNoteInputsmustbeXandtLabel.Text = {'Important Note:'; 'Inputs must be ( X ) and (t)'};
            app.SamplesPlotTab = uitab(app.TabGroup);
            app.SamplesPlotTab.Title = 'Samples Plot';
            app.RPPropertiesPlotTab = uitab(app.TabGroup);
            app.RPPropertiesPlotTab.Title = 'RP Properties Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPCalc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end